classdef SaveDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxesHR            matlab.ui.control.UIAxes
        UIAxesSPO2          matlab.ui.control.UIAxes
        AlarmsPanel         matlab.ui.container.Panel
        UITableAlarm        matlab.ui.control.Table
        DateandTimePanel    matlab.ui.container.Panel
        TimeEditFieldLabel  matlab.ui.control.Label
        TimeEditField       matlab.ui.control.EditField
        DateLabel           matlab.ui.control.Label
        DateEditField       matlab.ui.control.EditField
        PleaseregisterthepatientsCPRnumberbelowPanel  matlab.ui.container.Panel
        xxxxxxxxxxLabel     matlab.ui.control.Label
        CPROKButton         matlab.ui.control.Button
        CPRLabel            matlab.ui.control.Label
        CPREditField        matlab.ui.control.EditField
        SaveCancelPanel     matlab.ui.container.Panel
        SaveButton          matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        MonitorApp   % Main app objekt
        MeasurementTable
        AlarmTable
        AlarmThresholdsTable
        Time %Current time
        Date %Current date
        CurrentCPR  %CPR number of current patient
    end
    
    methods (Access = private)
        
        function PresentData(app)
            %Tabeller til test%%%%%%%%%%%%%%%%%%
            %MeasurementTable = table({'11:50';'11:55';'12:00';'12:05';'12:10'},[99;97;96;98;95],[120;140;110;120;130],[37;38;39;37;36]);
            %MeasurementTable.Properties.VariableNames = {'Time','O2sat','Pulse','Temp'};
            %%%%%
            
            
            %x-værdi sættes
            x = datetime(app.MeasurementTable.Time,'Format','HH:mm');
            %x =app.MeasurementTable.Time;
            
            %Plot HR data
            plot(app.UIAxesHR,x,app.MeasurementTable.HR,'-g')
            app.UIAxesHR.YLim = [min(app.MeasurementTable.HR) 200];
            title(app.UIAxesHR,'HR',"Color",'w')
            xlabel(app.UIAxesHR,'Time','Color','w')
            ylabel(app.UIAxesHR,'BPM','Color','w')
            
            hold(app.UIAxesHR); %holder aksen da der skal plottes af flere omgange
            
            %Plot SPO2 data
            plot(app.UIAxesSPO2,x,app.MeasurementTable.SpO2,'-','Color',"0.00,0.67,1.00")
            app.UIAxesSPO2.YLim = [min(app.MeasurementTable.SpO2) 110];
            title(app.UIAxesSPO2,'SPO2',"Color",'w')
            xlabel(app.UIAxesSPO2,'Time','Color','w')
            ylabel(app.UIAxesSPO2,'%','Color','w')
            
            hold(app.UIAxesSPO2);
            
            %Set current time on GUI
            app.Time = datestr(now, 'HH:MM');
            app.TimeEditField.Value = app.Time;
            
            %Set current date on GUI
            app.Date = datestr(now, 'dd/mm/yyyy');
            app.DateEditField.Value = app.Date;
        end
        
        function ValidateCPR(app)
            %Tjek om CPR-nummeret består af mere end tal - str2double returnerer NaN hvis string indeholder symboler eller bogstaver
            checkCPR = str2double(app.CPREditField.Value);
            if isnan(checkCPR)
                msgbox('Invalid CPR-number - It should only contain digits, try again!', 'Error','error');
            else
                %tjek om CPR-nummeret har det rigtige antal digits
                lengthOfCPR = length(app.CPREditField.Value);
                if lengthOfCPR < 10 || lengthOfCPR > 10
                    msgbox('Invalid CPR-number - It should consist of precisely 10 digits, try again!', 'Error','error');
                elseif lengthOfCPR==10
                    %Sæt det indtastede CPR-nummer til currentCPR
                    app.CurrentCPR = app.CPREditField.Value;
                    msgbox('The chosen CPR is now registered.')
                end
            end
        end
        
        function PresentAlarmData(app)
            %%%%%Test data
            %AlarmTable = table({'HR';'SPO2';'HR'},[50;80;40],['70';'90';'70'],[180;105;180],{'11:50';'11:55';'12:10'});
            %AlarmTable.Properties.VariableNames = {'MeasureType','MeasureValue','ThresholdMin','ThresholdMax','Time'};
            %%%%%
            
            %Plot alarmer på grafer %denne kode kan nok godt optimeres
            for alarm = 1:length(app.AlarmTable.MeasureType)
                if app.AlarmTable.MeasureType(alarm) == "HR"
                    plot(app.UIAxesHR,datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'),app.AlarmTable.MeasureValue(alarm),'*r')
                end
                
                if app.AlarmTable.MeasureType(alarm) == "SpO2"
                    plot(app.UIAxesSPO2,datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'),app.AlarmTable.MeasureValue(alarm),'*r')
                    
                end
            end
            %Update alarm table
            disp(app.AlarmTable)
            for alarm = 1:length(app.AlarmTable.MeasureType)
                if app.AlarmTable.ID(alarm) > 0
                    tabledata(alarm,:) = table(app.AlarmTable.MeasureType(alarm),app.AlarmTable.MeasureValue(alarm), app.AlarmTable.ThresholdMin(alarm),app.AlarmTable.ThresholdMax(alarm),datetime(app.AlarmTable.Time(alarm),'Format','HH:mm'));
                end
            end
            app.UITableAlarm.Data = tabledata;
            app.UITableAlarm.RowName ='numbered';
            % app.UITableAlarm.BackgroundColor = [0 0 0];
            % app.UITableAlarm.ForegroundColor = [0 0 0];
        end
        
        function PresentAlarmThresholds(app)
            %%%%%
            %ThresholdsTable = table([70;75;80],[180;190;180],[80;80;80],[105;105;101],[35;34;35],[40;41;40],{'11:50';'12:00';'12:10'});
            %ThresholdsTable.Properties.VariableNames = {'minHR','maxHR','minSPO2','maxSPO2','minTemp','maxTemp','TimeChanged'};
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Plot grænseværdier
            for threshold = 1:length(app.AlarmThresholdsTable.TimeChanged)
                if threshold <= length(app.AlarmThresholdsTable.TimeChanged)-1 %ellers kan den ikke finde ud af at der ikke er et tidspunkt efter
                    x1 = datetime(app.AlarmThresholdsTable.TimeChanged(threshold),'Format','HH:mm');
                    x2 = datetime(app.AlarmThresholdsTable.TimeChanged(threshold+1),'Format','HH:mm');
                    
                    %HR
                    y1 = app.AlarmThresholdsTable.minHR(threshold);
                    line(app.UIAxesHR,[x1,x2],[y1,y1],'LineStyle','--','Color','b') %Skal have fikset legend
                    y2 = app.AlarmThresholdsTable.maxHR(threshold);
                    line(app.UIAxesHR,[x1,x2],[y2,y2],'LineStyle','--','Color','b')
                    
                    %SPO2
                    y3 = app.AlarmThresholdsTable.minSPO2(threshold);
                    line(app.UIAxesSPO2,[x1,x2],[y3,y3],'LineStyle','--','Color','b') %Skal have fikset legend
                    y4 = app.AlarmThresholdsTable.maxSPO2(threshold);
                    line(app.UIAxesSPO2,[x1,x2],[y4,y4],'LineStyle','--','Color','b')
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, datatable, alarmtable, alarmthresholdtable)
            % Store main app in property for CloseRequestFcn to use
            app.MonitorApp = mainapp;
            app.MeasurementTable = datatable;
            app.AlarmTable = alarmtable;
            app.AlarmThresholdsTable = alarmthresholdtable;
            
            app.PresentData();
            app.PresentAlarmData();
            app.PresentAlarmThresholds();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.CurrentCPR) == 0 %Isempty returnerer 0 hvis CPR ikke er tom
                %Tag screenshot
                img = takeScreenDump(); %metode ligger i scriptet
                
                %Generatefilename
                new_currentDate = strrep(app.Date,'/','-');
                new_currentTime = strrep(app.Time,':','.');
                formatSpec = 'CPR%s_Date%s_EndTime%s.png';
                file_name = sprintf(formatSpec,app.CurrentCPR,new_currentDate,new_currentTime);
                
                %Save image
                imwrite(img,file_name) %unikt navn med CPR, dato og sluttid
                
                %Messagedialog
                msgbox('The measurements were succesfully saved!','Success');
                
                %Vinduet skal lukkes og returneres til startsiden
                delete(app)
            else
                msgbox('The patients CPR-number must be registered before saving the measurements. Please register the CPR-number and try again!', 'Error','error');
            end
        end

        % Button pushed function: CPROKButton
        function CPROKButtonPushed(app, event)
            %Tjek CPR
            app.ValidateCPR();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxesHR = uiaxes(app.UIFigure);
            title(app.UIAxesHR, 'Puls')
            xlabel(app.UIAxesHR, 'Tid')
            ylabel(app.UIAxesHR, 'BPM')
            app.UIAxesSPO2 = uiaxes(app.UIFigure);
            title(app.UIAxesSPO2, 'Iltmætning')
            xlabel(app.UIAxesSPO2, 'Tid')
            ylabel(app.UIAxesSPO2, '%')
            app.AlarmsPanel = uipanel(app.UIFigure);
            app.AlarmsPanel.Title = 'Alarms:';
            app.UITableAlarm = uitable(app.AlarmsPanel);
            app.UITableAlarm.ColumnName = {'Type'; 'Value'; 'TMin'; 'TMax'; 'Time'};
            app.UITableAlarm.RowName = {''; ''};
            app.DateandTimePanel = uipanel(app.UIFigure);
            app.DateandTimePanel.Title = 'Date and Time:';
            app.TimeEditFieldLabel = uilabel(app.DateandTimePanel);
            app.TimeEditFieldLabel.Text = 'Time:';
            app.TimeEditField = uieditfield(app.DateandTimePanel, 'text');
            app.TimeEditField.Enable = 'off';
            app.DateLabel = uilabel(app.DateandTimePanel);
            app.DateLabel.Text = 'Date:';
            app.DateEditField = uieditfield(app.DateandTimePanel, 'text');
            app.DateEditField.Enable = 'off';
            app.PleaseregisterthepatientsCPRnumberbelowPanel = uipanel(app.UIFigure);
            app.PleaseregisterthepatientsCPRnumberbelowPanel.Title = 'Please register the patient''s CPR number below: ';
            app.xxxxxxxxxxLabel = uilabel(app.PleaseregisterthepatientsCPRnumberbelowPanel);
            app.xxxxxxxxxxLabel.Text = '(xxxxxxxxxx)';
            app.CPROKButton = uibutton(app.PleaseregisterthepatientsCPRnumberbelowPanel, 'push');
            app.CPROKButton.ButtonPushedFcn = createCallbackFcn(app, @CPROKButtonPushed, true);
            app.CPROKButton.Text = 'OK';
            app.CPRLabel = uilabel(app.PleaseregisterthepatientsCPRnumberbelowPanel);
            app.CPRLabel.Text = 'CPR:';
            app.CPREditField = uieditfield(app.PleaseregisterthepatientsCPRnumberbelowPanel, 'text');
            app.SaveCancelPanel = uipanel(app.UIFigure);
            app.SaveCancelPanel.Title = 'Save/Cancel';
            app.SaveButton = uibutton(app.SaveCancelPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save-file.png';
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.SaveCancelPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end